classdef Assignment1App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RoboticsAssignment1UIFigure     matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SceneInitialisationTab          matlab.ui.container.Tab
        UR31BaseLabel                   matlab.ui.control.Label
        XEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        ZEditField                      matlab.ui.control.NumericEditField
        OrientationKnobLabel            matlab.ui.control.Label
        OrientationKnob                 matlab.ui.control.Knob
        PositionLabel                   matlab.ui.control.Label
        UR32BaseLabel                   matlab.ui.control.Label
        PositionLabel_2                 matlab.ui.control.Label
        XEditField_2Label               matlab.ui.control.Label
        XEditField_2                    matlab.ui.control.NumericEditField
        YEditField_2Label               matlab.ui.control.Label
        YEditField_2                    matlab.ui.control.NumericEditField
        ZEditField_2Label               matlab.ui.control.Label
        ZEditField_2                    matlab.ui.control.NumericEditField
        OrientationKnob_2Label          matlab.ui.control.Label
        OrientationKnob_2               matlab.ui.control.Knob
        AssemblyPartsLabel              matlab.ui.control.Label
        XEditField_3Label               matlab.ui.control.Label
        XEditField_3                    matlab.ui.control.NumericEditField
        YEditField_3Label               matlab.ui.control.Label
        YEditField_3                    matlab.ui.control.NumericEditField
        ZEditField_3Label               matlab.ui.control.Label
        ZEditField_3                    matlab.ui.control.NumericEditField
        HousingTopLabel                 matlab.ui.control.Label
        HousingBotLabel                 matlab.ui.control.Label
        XEditField_4Label               matlab.ui.control.Label
        XEditField_4                    matlab.ui.control.NumericEditField
        YEditField_4Label               matlab.ui.control.Label
        YEditField_4                    matlab.ui.control.NumericEditField
        ZEditField_4Label               matlab.ui.control.Label
        ZEditField_4                    matlab.ui.control.NumericEditField
        PCBLabel                        matlab.ui.control.Label
        XEditField_5Label               matlab.ui.control.Label
        XEditField_5                    matlab.ui.control.NumericEditField
        YEditField_5Label               matlab.ui.control.Label
        YEditField_5                    matlab.ui.control.NumericEditField
        ZEditField_5Label               matlab.ui.control.Label
        ZEditField_5                    matlab.ui.control.NumericEditField
        DropOffLabel                    matlab.ui.control.Label
        DropofflocationLabel            matlab.ui.control.Label
        XEditField_6Label               matlab.ui.control.Label
        XEditField_6                    matlab.ui.control.NumericEditField
        YEditField_6Label               matlab.ui.control.Label
        YEditField_6                    matlab.ui.control.NumericEditField
        ZEditField_6Label               matlab.ui.control.Label
        ZEditField_6                    matlab.ui.control.NumericEditField
        RenderSceneButton               matlab.ui.control.Button
        AssemblyTaskTab                 matlab.ui.container.Tab
        ConfigureRobotsControllersLabel  matlab.ui.control.Label
        InterpolationMethodDropDownLabel  matlab.ui.control.Label
        InterpolationMethodDropDown     matlab.ui.control.DropDown
        NumberofStepsEditFieldLabel     matlab.ui.control.Label
        NumberofStepsEditField          matlab.ui.control.EditField
        ASSEMBLEButton                  matlab.ui.control.Button
        CONFIGUREButton                 matlab.ui.control.Button
        DISASSEMBLEButton               matlab.ui.control.Button
        DebugModeTab                    matlab.ui.container.Tab
        Q1EditFieldLabel                matlab.ui.control.Label
        Q1EditField                     matlab.ui.control.NumericEditField
        Q2EditFieldLabel                matlab.ui.control.Label
        Q2EditField                     matlab.ui.control.NumericEditField
        Q3EditFieldLabel                matlab.ui.control.Label
        Q3EditField                     matlab.ui.control.NumericEditField
        Q4EditFieldLabel                matlab.ui.control.Label
        Q4EditField                     matlab.ui.control.NumericEditField
        Q5EditFieldLabel                matlab.ui.control.Label
        Q5EditField                     matlab.ui.control.NumericEditField
        Q6EditFieldLabel                matlab.ui.control.Label
        Q6EditField                     matlab.ui.control.NumericEditField
        Q1EditField_2Label              matlab.ui.control.Label
        Q1EditField_2                   matlab.ui.control.NumericEditField
        Q2EditField_2Label              matlab.ui.control.Label
        Q2EditField_2                   matlab.ui.control.NumericEditField
        Q3EditField_2Label              matlab.ui.control.Label
        Q3EditField_2                   matlab.ui.control.NumericEditField
        Q4EditField_2Label              matlab.ui.control.Label
        Q4EditField_2                   matlab.ui.control.NumericEditField
        Q5EditField_2Label              matlab.ui.control.Label
        Q5EditField_2                   matlab.ui.control.NumericEditField
        Q6EditField_2Label              matlab.ui.control.Label
        Q6EditField_2                   matlab.ui.control.NumericEditField
        GotoEndEffectorPoseLabel        matlab.ui.control.Label
        UR31endXEditFieldLabel          matlab.ui.control.Label
        UR31endXEditField               matlab.ui.control.NumericEditField
        UR31endYEditFieldLabel          matlab.ui.control.Label
        UR31endYEditField               matlab.ui.control.NumericEditField
        UR31endZEditFieldLabel          matlab.ui.control.Label
        UR31endZEditField               matlab.ui.control.NumericEditField
        UR31endRollEditFieldLabel       matlab.ui.control.Label
        UR31endRollEditField            matlab.ui.control.NumericEditField
        UR31endPitchEditFieldLabel      matlab.ui.control.Label
        UR31endPitchEditField           matlab.ui.control.NumericEditField
        UR31endYawEditFieldLabel        matlab.ui.control.Label
        UR31endYawEditField             matlab.ui.control.NumericEditField
        UR32endXEditFieldLabel          matlab.ui.control.Label
        UR32endXEditField               matlab.ui.control.NumericEditField
        UR32endYEditFieldLabel          matlab.ui.control.Label
        UR32endYEditField               matlab.ui.control.NumericEditField
        UR32endZEditFieldLabel          matlab.ui.control.Label
        UR32endZEditField               matlab.ui.control.NumericEditField
        UR32endRollEditFieldLabel       matlab.ui.control.Label
        UR32endRollEditField            matlab.ui.control.NumericEditField
        UR32endPitchEditFieldLabel      matlab.ui.control.Label
        UR32endPitchEditField           matlab.ui.control.NumericEditField
        UR32endYawEditFieldLabel        matlab.ui.control.Label
        UR32endYawEditField             matlab.ui.control.NumericEditField
        GoButton                        matlab.ui.control.Button
        WorkspaceCalculationsLabel      matlab.ui.control.Label
        UR31RadiusEditFieldLabel        matlab.ui.control.Label
        UR31RadiusEditField             matlab.ui.control.NumericEditField
        UR31VolumeConvexLabel           matlab.ui.control.Label
        UR31VolumeConvexEditField       matlab.ui.control.NumericEditField
        UR32RadiusEditFieldLabel        matlab.ui.control.Label
        UR32RadiusEditField             matlab.ui.control.NumericEditField
        UR32VolumeConvexEditFieldLabel  matlab.ui.control.Label
        UR32VolumeConvexEditField       matlab.ui.control.NumericEditField
        UR31PCButton                    matlab.ui.control.Button
        UR32PCButton                    matlab.ui.control.Button
        GeneratePointCloudandRecalculateLabel  matlab.ui.control.Label
        UR31PCButton_2                  matlab.ui.control.Button
        UR32PCButton_2                  matlab.ui.control.Button
        PlotPointCloudLabel             matlab.ui.control.Label
        StepAngleEditFieldLabel         matlab.ui.control.Label
        StepAngleEditField              matlab.ui.control.NumericEditField
        UR31JointAnglesdegCheckBox      matlab.ui.control.CheckBox
        UR32JointAnglesdegCheckBox      matlab.ui.control.CheckBox
        HomeButton                      matlab.ui.control.Button
        DebugTextAreaLabel              matlab.ui.control.Label
        DebugTextArea                   matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        sessionDatetime;        % Current datetime (for logging)
        sessionDebugLogFile;    % Debug log file of session
        sessionQLogFile;        % Joint angles log file of session
        
        debugWindowText = '';
        
        simuScene;
        ur31PCTimer = timer('StartDelay', 0.5, 'Period', 2, 'TasksToExecute', Inf,'ExecutionMode', 'fixedRate');
        ur32PCTimer = timer('StartDelay', 0.5, 'Period', 2, 'TasksToExecute', Inf,'ExecutionMode', 'fixedRate');
        
        ur31QTimer = timer('StartDelay', 0.5, 'Period', 0.2, 'TasksToExecute', Inf,'ExecutionMode', 'fixedRate');
        UR31QEnable = 0;
        ur32QTimer = timer('StartDelay', 0.5, 'Period', 0.2, 'TasksToExecute', Inf,'ExecutionMode', 'fixedRate');
        UR32QEnable = 0;
    end
    
    methods (Access = public)
        
        function LogToDebugFile(app, text)
            fileID = fopen(app.sessionDebugLogFile,'a+');
            printText = datestr(now,'[yyyy-mm-dd HH:MM:SS] ') + text + '\n';
            fprintf(fileID,printText);
            fclose(fileID);
        end
        
        function DebugAndLog(app, text)
            app.debugWindowText = sprintf("%s\n%s",text,app.debugWindowText);
            app.DebugTextArea.Value = app.debugWindowText;
            app.LogToDebugFile(text);
        end
    end
    
    methods (Access = private)
        
        function UpdateUR31Q(app)
            if ~app.UR31QEnable
               return 
            end
            Q = app.simuScene.GetPosUR31();
            Q = rad2deg(Q);
            app.Q1EditField.Value = Q(1);
            app.Q2EditField.Value = Q(2);
            app.Q3EditField.Value = Q(3);
            app.Q4EditField.Value = Q(4);
            app.Q5EditField.Value = Q(5);
            app.Q6EditField.Value = Q(6);
        end
    
        function UpdateUR32Q(app)
            if ~app.UR32QEnable
               return 
            end
            Q = app.simuScene.GetPosUR32();
            Q = rad2deg(Q);
            app.Q1EditField_2.Value = Q(1);
            app.Q2EditField_2.Value = Q(2);
            app.Q3EditField_2.Value = Q(3);
            app.Q4EditField_2.Value = Q(4);
            app.Q5EditField_2.Value = Q(5);
            app.Q6EditField_2.Value = Q(6);
        end
        
        function LogUR31PCProgress(app)
            progress = app.simuScene.GetUR31PCProgress();
            if progress < 100
                app.DebugAndLog(sprintf("Point Cloud Generation - Completed %0.0f percent of poses", progress));
            else
                stop(app.ur31PCTimer);
            end
        end
        
        function LogUR32PCProgress(app)
            progress = app.simuScene.GetUR32PCProgress();
            if progress < 100
                app.DebugAndLog(sprintf("Point Cloud Generation - Completed %0.0f percent of poses", progress));
            else
                stop(app.ur32PCTimer);
            end
        end
    
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.sessionDatetime = now;
            datetimestr = datestr(app.sessionDatetime,'yyyy_mm_dd_HH_MM_SS');
            app.sessionDebugLogFile = "./logs/" + datetimestr + "_session_log.txt";
            app.DebugAndLog("Started Session");
        end

        % Button pushed function: RenderSceneButton
        function RenderSceneButtonPushed(app, event)
            % disable some buttons
            app.UR31PCButton_2.Enable = false;
            app.UR32PCButton_2.Enable = false;
            app.GoButton.Enable = false;
            app.HomeButton.Enable = false;
            
            % stops polling joint angles
            stop(app.ur31QTimer);
            stop(app.ur32QTimer);
            
            % creates figure
            app.simuScene = SimulationScene();
            
            % places UR3 #1 in scene
            UR31X = app.XEditField.Value;
            UR31Y = app.YEditField.Value;
            UR31Z = app.ZEditField.Value;
            UR31Theta = app.OrientationKnob.Value;
            app.simuScene.PlaceUR31(UR31X, UR31Y, UR31Z, UR31Theta);
            app.DebugAndLog(sprintf("Placed UR3 #1 at [%0.4f, %0.4f, %0.4f, %0.4f]", UR31X, UR31Y, UR31Z, UR31Theta));
            
            % places UR3 #2 in scene
            UR32X = app.XEditField_2.Value;
            UR32Y = app.YEditField_2.Value;
            UR32Z = app.ZEditField_2.Value;
            UR32Theta = app.OrientationKnob_2.Value;
            app.simuScene.PlaceUR32(UR32X, UR32Y, UR32Z, UR32Theta);
            app.DebugAndLog(sprintf("Placed UR3 #2 at [%0.4f, %0.4f, %0.4f, %0.4f]", UR32X, UR32Y, UR32Z, UR32Theta));
            
            % places assembly parts in scene
            AssemTopX = app.XEditField_3.Value;
            AssemTopY = app.YEditField_3.Value;
            AssemTopZ = app.ZEditField_3.Value;
            app.simuScene.PlaceAssemTop(AssemTopX,AssemTopY,AssemTopZ);
            app.DebugAndLog(sprintf("Placed Top Housing at [%0.4f, %0.4f, %0.4f]", AssemTopX, AssemTopY, AssemTopZ));
            
            AssemBotX = app.XEditField_4.Value;
            AssemBotY = app.YEditField_4.Value;
            AssemBotZ = app.ZEditField_4.Value;
            app.simuScene.PlaceAssemBot(AssemBotX,AssemBotY,AssemBotZ);
            app.DebugAndLog(sprintf("Placed Bottom Housing at [%0.4f, %0.4f, %0.4f]", AssemBotX, AssemBotX, AssemBotZ));
            
            AssemPCBX = app.XEditField_5.Value;
            AssemPCBY = app.YEditField_5.Value;
            AssemPCBZ = app.ZEditField_5.Value;
            app.simuScene.PlaceAssemPCB(AssemPCBX,AssemPCBY,AssemPCBZ);
            app.DebugAndLog(sprintf("Placed PCB at [%0.4f, %0.4f, %0.4f]", AssemPCBX, AssemPCBY, AssemPCBZ));
            
            dropOffX = app.XEditField_6.Value;
            dropOffY = app.YEditField_6.Value;
            dropOffZ = app.ZEditField_6.Value;
            app.simuScene.PlaceDropOff(dropOffX,dropOffY,dropOffZ);
            app.DebugAndLog(sprintf("Placed Drop Off Area at [%0.4f, %0.4f, %0.4f]", dropOffX, dropOffY, dropOffZ));
            
            % generates environment around assembly area
            app.simuScene.GenerateScene();
            app.DebugAndLog("Generated Assembly Environment");
            
            % starts polling joint angles
            app.ur31QTimer.TimerFcn = @(obj, event)UpdateUR31Q(app);
            app.ur32QTimer.TimerFcn = @(obj, event)UpdateUR32Q(app);
            start(app.ur31QTimer);
            start(app.ur32QTimer);
            
        end

        % Button pushed function: ASSEMBLEButton
        function ASSEMBLEButtonPushed(app, event)
            app.ASSEMBLEButton.Enable = false;
            app.DISASSEMBLEButton.Enable = false;
            AssemblyTask(app.simuScene, app);
            app.DISASSEMBLEButton.Enable = true;
        end

        % Button pushed function: CONFIGUREButton
        function CONFIGUREButtonPushed(app, event)
            method = 1;
            if app.InterpolationMethodDropDown.Value == "Quintic Polynomial"
                app.DebugAndLog("Quintic Polynomial method chosen");
                method = 1;
            elseif app.InterpolationMethodDropDown.Value == "Trapezoidal Velocity Profile"
                app.DebugAndLog("Trapezoidal Velocity Profile method chosen");
                method = 2;
            end
            steps = str2double(app.NumberofStepsEditField.Value);
            app.simuScene.ChooseInterpolation(method,steps);
            app.ASSEMBLEButton.Enable = true;
            app.GoButton.Enable = true;
            app.HomeButton.Enable = true;
            app.DebugAndLog(sprintf("Number of interpolation steps is %d", steps));
        end

        % Button pushed function: UR31PCButton
        function UR31PCButtonPushed(app, event)
            app.UR31PCButton.Enable = false;
            app.UR31PCButton_2.Enable = false;
            stepAngle = app.StepAngleEditField.Value;
            
            app.ur31PCTimer.TimerFcn = @(obj, event)LogUR31PCProgress(app);
            start(app.ur31PCTimer);
            
            app.DebugAndLog(sprintf("Generating UR31 PointCloud at %0.1f deg step angle ...",stepAngle));
            app.simuScene.GenerateUR31PC(stepAngle);
            app.UR31PCButton.Enable = true;
            app.UR31PCButton_2.Enable = true;
            radius = app.simuScene.GetUR31Radius();
            volume = app.simuScene.GetUR31Volume();
            app.UR31RadiusEditField.Value = radius;
            app.UR31VolumeConvexEditField.Value = volume;
            app.DebugAndLog(sprintf("Point Cloud Generation - Finished. UR31 radius: %0.3fm, volume: %0.3fm3", radius, volume));
        end

        % Button pushed function: UR32PCButton
        function UR32PCButtonPushed(app, event)
            app.UR32PCButton.Enable = false;
            app.UR32PCButton_2.Enable = false;
            stepAngle = app.StepAngleEditField.Value;
            
            app.ur32PCTimer.TimerFcn = @(obj, event)LogUR32PCProgress(app);
            start(app.ur32PCTimer);
            
            app.DebugAndLog(sprintf("Generating UR32 PointCloud at %0.1f deg step angle ...",stepAngle));
            app.simuScene.GenerateUR32PC(stepAngle);
            app.UR32PCButton.Enable = true;
            app.UR32PCButton_2.Enable = true;
            radius = app.simuScene.GetUR32Radius();
            volume = app.simuScene.GetUR32Volume();
            app.UR32RadiusEditField.Value = radius;
            app.UR32VolumeConvexEditField.Value = volume;
            app.DebugAndLog(sprintf("Point Cloud Generation - Finished. UR32 radius: %0.3fm, volume: %0.3fm3", radius, volume));
        end

        % Button pushed function: UR31PCButton_2
        function UR31PCButton_2Pushed(app, event)
            app.simuScene.PlotUR31PC();
        end

        % Button pushed function: UR32PCButton_2
        function UR32PCButton_2Pushed(app, event)
            app.simuScene.PlotUR32PC();
        end

        % Value changed function: UR31JointAnglesdegCheckBox
        function UR31JointAnglesdegCheckBoxValueChanged(app, event)
            app.UR31QEnable = app.UR31JointAnglesdegCheckBox.Value;
        end

        % Value changed function: UR32JointAnglesdegCheckBox
        function UR32JointAnglesdegCheckBoxValueChanged(app, event)
            app.UR32QEnable = app.UR32JointAnglesdegCheckBox.Value;
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            ur31X = app.UR31endXEditField.Value;
            ur31Y = app.UR31endYEditField.Value;
            ur31Z = app.UR31endZEditField.Value;
            ur31Roll = app.UR31endRollEditField.Value;
            ur31Pitch = app.UR31endPitchEditField.Value;
            ur31Yaw = app.UR31endYawEditField.Value;
            
            ur31EndPose = transl(ur31X,ur31Y,ur31Z);
            ur31EndRotM = eul2tform(deg2rad([ur31Roll,ur31Pitch,ur31Yaw]));
            ur31EndPose = ur31EndPose*ur31EndRotM;
            
            ur32X = app.UR32endXEditField.Value;
            ur32Y = app.UR32endYEditField.Value;
            ur32Z = app.UR32endZEditField.Value;
            ur32Roll = app.UR32endRollEditField.Value;
            ur32Pitch = app.UR32endPitchEditField.Value;
            ur32Yaw = app.UR32endYawEditField.Value;
            
            ur32EndPose = transl(ur32X,ur32Y,ur32Z);
            ur32EndRotM = eul2tform(deg2rad([ur32Roll,ur32Pitch,ur32Yaw]));
            ur32EndPose = ur32EndPose*ur32EndRotM;
            
            app.DebugAndLog("Driving both robots to custom pose ...");
            app.simuScene.DriveBothToPose(ur31EndPose,ur32EndPose);
        end

        % Close request function: RoboticsAssignment1UIFigure
        function RoboticsAssignment1UIFigureCloseRequest(app, event)
            stop(app.ur31QTimer);
            stop(app.ur32QTimer);
            delete(app)  
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app.DebugAndLog("Driving both robots home ...");
            app.simuScene.DriveBothHome();
        end

        % Button pushed function: DISASSEMBLEButton
        function DISASSEMBLEButtonPushed(app, event)
            % places assembly parts in scene
            AssemTopX = app.XEditField_3.Value;
            AssemTopY = app.YEditField_3.Value;
            AssemTopZ = app.ZEditField_3.Value;
            app.simuScene.PlaceAssemTop(AssemTopX,AssemTopY,AssemTopZ);
            app.DebugAndLog(sprintf("Placed Top Housing at [%0.4f, %0.4f, %0.4f]", AssemTopX, AssemTopY, AssemTopZ));
            
            AssemBotX = app.XEditField_4.Value;
            AssemBotY = app.YEditField_4.Value;
            AssemBotZ = app.ZEditField_4.Value;
            app.simuScene.PlaceAssemBot(AssemBotX,AssemBotY,AssemBotZ);
            app.DebugAndLog(sprintf("Placed Bottom Housing at [%0.4f, %0.4f, %0.4f]", AssemBotX, AssemBotX, AssemBotZ));
            
            AssemPCBX = app.XEditField_5.Value;
            AssemPCBY = app.YEditField_5.Value;
            AssemPCBZ = app.ZEditField_5.Value;
            app.simuScene.PlaceAssemPCB(AssemPCBX,AssemPCBY,AssemPCBZ);
            app.DebugAndLog(sprintf("Placed PCB at [%0.4f, %0.4f, %0.4f]", AssemPCBX, AssemPCBY, AssemPCBZ));
            
            app.ASSEMBLEButton.Enable = true;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RoboticsAssignment1UIFigure = uifigure;
            app.RoboticsAssignment1UIFigure.Name = 'Robotics Assignment 1';
            app.RoboticsAssignment1UIFigure.CloseRequestFcn = createCallbackFcn(app, @RoboticsAssignment1UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.RoboticsAssignment1UIFigure);
            app.SceneInitialisationTab = uitab(app.TabGroup);
            app.SceneInitialisationTab.Title = 'Scene Initialisation';
            app.UR31BaseLabel = uilabel(app.SceneInitialisationTab);
            app.UR31BaseLabel.Text = 'UR3 1 Base';
            app.XEditFieldLabel = uilabel(app.SceneInitialisationTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.SceneInitialisationTab, 'numeric');
            app.XEditField.Value = -0.23;
            app.YEditFieldLabel = uilabel(app.SceneInitialisationTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.SceneInitialisationTab, 'numeric');
            app.YEditField.Value = 0.23;
            app.ZEditFieldLabel = uilabel(app.SceneInitialisationTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.SceneInitialisationTab, 'numeric');
            app.OrientationKnobLabel = uilabel(app.SceneInitialisationTab);
            app.OrientationKnobLabel.Text = 'Orientation';
            app.OrientationKnob = uiknob(app.SceneInitialisationTab, 'continuous');
            app.OrientationKnob.Limits = [-180 180];
            app.PositionLabel = uilabel(app.SceneInitialisationTab);
            app.PositionLabel.Text = 'Position';
            app.UR32BaseLabel = uilabel(app.SceneInitialisationTab);
            app.UR32BaseLabel.Text = 'UR3 2 Base';
            app.PositionLabel_2 = uilabel(app.SceneInitialisationTab);
            app.PositionLabel_2.Text = 'Position';
            app.XEditField_2Label = uilabel(app.SceneInitialisationTab);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.SceneInitialisationTab, 'numeric');
            app.XEditField_2.Value = 0.23;
            app.YEditField_2Label = uilabel(app.SceneInitialisationTab);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.SceneInitialisationTab, 'numeric');
            app.YEditField_2.Value = 0.23;
            app.ZEditField_2Label = uilabel(app.SceneInitialisationTab);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.SceneInitialisationTab, 'numeric');
            app.OrientationKnob_2Label = uilabel(app.SceneInitialisationTab);
            app.OrientationKnob_2Label.Text = 'Orientation';
            app.OrientationKnob_2 = uiknob(app.SceneInitialisationTab, 'continuous');
            app.OrientationKnob_2.Limits = [-180 180];
            app.AssemblyPartsLabel = uilabel(app.SceneInitialisationTab);
            app.AssemblyPartsLabel.Text = 'Assembly Parts';
            app.XEditField_3Label = uilabel(app.SceneInitialisationTab);
            app.XEditField_3Label.Text = 'X';
            app.XEditField_3 = uieditfield(app.SceneInitialisationTab, 'numeric');
            app.XEditField_3.Value = -0.42;
            app.YEditField_3Label = uilabel(app.SceneInitialisationTab);
            app.YEditField_3Label.Text = 'Y';
            app.YEditField_3 = uieditfield(app.SceneInitialisationTab, 'numeric');
            app.YEditField_3.Value = 0.46;
            app.ZEditField_3Label = uilabel(app.SceneInitialisationTab);
            app.ZEditField_3Label.Text = 'Z';
            app.ZEditField_3 = uieditfield(app.SceneInitialisationTab, 'numeric');
            app.ZEditField_3.Value = 0.02;
            app.HousingTopLabel = uilabel(app.SceneInitialisationTab);
            app.HousingTopLabel.Text = 'Housing - Top';
            app.HousingBotLabel = uilabel(app.SceneInitialisationTab);
            app.HousingBotLabel.Text = 'Housing - Bot';
            app.XEditField_4Label = uilabel(app.SceneInitialisationTab);
            app.XEditField_4Label.Text = 'X';
            app.XEditField_4 = uieditfield(app.SceneInitialisationTab, 'numeric');
            app.XEditField_4.Value = 0.42;
            app.YEditField_4Label = uilabel(app.SceneInitialisationTab);
            app.YEditField_4Label.Text = 'Y';
            app.YEditField_4 = uieditfield(app.SceneInitialisationTab, 'numeric');
            app.YEditField_4.Value = 0.46;
            app.ZEditField_4Label = uilabel(app.SceneInitialisationTab);
            app.ZEditField_4Label.Text = 'Z';
            app.ZEditField_4 = uieditfield(app.SceneInitialisationTab, 'numeric');
            app.ZEditField_4.Value = 0.03;
            app.PCBLabel = uilabel(app.SceneInitialisationTab);
            app.PCBLabel.Text = 'PCB';
            app.XEditField_5Label = uilabel(app.SceneInitialisationTab);
            app.XEditField_5Label.Text = 'X';
            app.XEditField_5 = uieditfield(app.SceneInitialisationTab, 'numeric');
            app.YEditField_5Label = uilabel(app.SceneInitialisationTab);
            app.YEditField_5Label.Text = 'Y';
            app.YEditField_5 = uieditfield(app.SceneInitialisationTab, 'numeric');
            app.YEditField_5.Value = 0.46;
            app.ZEditField_5Label = uilabel(app.SceneInitialisationTab);
            app.ZEditField_5Label.Text = 'Z';
            app.ZEditField_5 = uieditfield(app.SceneInitialisationTab, 'numeric');
            app.ZEditField_5.Value = 0.01;
            app.DropOffLabel = uilabel(app.SceneInitialisationTab);
            app.DropOffLabel.Text = 'Drop Off';
            app.DropofflocationLabel = uilabel(app.SceneInitialisationTab);
            app.DropofflocationLabel.Text = 'Drop-off location';
            app.XEditField_6Label = uilabel(app.SceneInitialisationTab);
            app.XEditField_6Label.Text = 'X';
            app.XEditField_6 = uieditfield(app.SceneInitialisationTab, 'numeric');
            app.XEditField_6.Value = 0.25;
            app.YEditField_6Label = uilabel(app.SceneInitialisationTab);
            app.YEditField_6Label.Text = 'Y';
            app.YEditField_6 = uieditfield(app.SceneInitialisationTab, 'numeric');
            app.YEditField_6.Value = -0.1;
            app.ZEditField_6Label = uilabel(app.SceneInitialisationTab);
            app.ZEditField_6Label.Text = 'Z';
            app.ZEditField_6 = uieditfield(app.SceneInitialisationTab, 'numeric');
            app.ZEditField_6.Value = 0.19;
            app.RenderSceneButton = uibutton(app.SceneInitialisationTab, 'push');
            app.RenderSceneButton.ButtonPushedFcn = createCallbackFcn(app, @RenderSceneButtonPushed, true);
            app.RenderSceneButton.Text = 'Render Scene';
            app.AssemblyTaskTab = uitab(app.TabGroup);
            app.AssemblyTaskTab.Title = 'Assembly Task';
            app.ConfigureRobotsControllersLabel = uilabel(app.AssemblyTaskTab);
            app.ConfigureRobotsControllersLabel.Text = 'Configure Robots Controllers';
            app.InterpolationMethodDropDownLabel = uilabel(app.AssemblyTaskTab);
            app.InterpolationMethodDropDownLabel.Text = 'Interpolation Method';
            app.InterpolationMethodDropDown = uidropdown(app.AssemblyTaskTab);
            app.InterpolationMethodDropDown.Items = {'Quintic Polynomial', 'Trapezoidal Velocity Profile'};
            app.InterpolationMethodDropDown.Value = 'Quintic Polynomial';
            app.NumberofStepsEditFieldLabel = uilabel(app.AssemblyTaskTab);
            app.NumberofStepsEditFieldLabel.Text = 'Number of Steps';
            app.NumberofStepsEditField = uieditfield(app.AssemblyTaskTab, 'text');
            app.NumberofStepsEditField.Value = '150';
            app.ASSEMBLEButton = uibutton(app.AssemblyTaskTab, 'push');
            app.ASSEMBLEButton.ButtonPushedFcn = createCallbackFcn(app, @ASSEMBLEButtonPushed, true);
            app.ASSEMBLEButton.Enable = 'off';
            app.ASSEMBLEButton.Text = 'ASSEMBLE';
            app.CONFIGUREButton = uibutton(app.AssemblyTaskTab, 'push');
            app.CONFIGUREButton.ButtonPushedFcn = createCallbackFcn(app, @CONFIGUREButtonPushed, true);
            app.CONFIGUREButton.Text = 'CONFIGURE';
            app.DISASSEMBLEButton = uibutton(app.AssemblyTaskTab, 'push');
            app.DISASSEMBLEButton.ButtonPushedFcn = createCallbackFcn(app, @DISASSEMBLEButtonPushed, true);
            app.DISASSEMBLEButton.Text = 'DISASSEMBLE';
            app.DebugModeTab = uitab(app.TabGroup);
            app.DebugModeTab.Title = 'Debug Mode';
            app.Q1EditFieldLabel = uilabel(app.DebugModeTab);
            app.Q1EditFieldLabel.Text = 'Q1';
            app.Q1EditField = uieditfield(app.DebugModeTab, 'numeric');
            app.Q1EditField.Editable = 'off';
            app.Q2EditFieldLabel = uilabel(app.DebugModeTab);
            app.Q2EditFieldLabel.Text = 'Q2';
            app.Q2EditField = uieditfield(app.DebugModeTab, 'numeric');
            app.Q2EditField.Editable = 'off';
            app.Q3EditFieldLabel = uilabel(app.DebugModeTab);
            app.Q3EditFieldLabel.Text = 'Q3';
            app.Q3EditField = uieditfield(app.DebugModeTab, 'numeric');
            app.Q3EditField.Editable = 'off';
            app.Q4EditFieldLabel = uilabel(app.DebugModeTab);
            app.Q4EditFieldLabel.Text = 'Q4';
            app.Q4EditField = uieditfield(app.DebugModeTab, 'numeric');
            app.Q4EditField.Editable = 'off';
            app.Q5EditFieldLabel = uilabel(app.DebugModeTab);
            app.Q5EditFieldLabel.Text = 'Q5';
            app.Q5EditField = uieditfield(app.DebugModeTab, 'numeric');
            app.Q5EditField.Editable = 'off';
            app.Q6EditFieldLabel = uilabel(app.DebugModeTab);
            app.Q6EditFieldLabel.Text = 'Q6';
            app.Q6EditField = uieditfield(app.DebugModeTab, 'numeric');
            app.Q6EditField.Editable = 'off';
            app.Q1EditField_2Label = uilabel(app.DebugModeTab);
            app.Q1EditField_2Label.Text = 'Q1';
            app.Q1EditField_2 = uieditfield(app.DebugModeTab, 'numeric');
            app.Q1EditField_2.Editable = 'off';
            app.Q2EditField_2Label = uilabel(app.DebugModeTab);
            app.Q2EditField_2Label.Text = 'Q2';
            app.Q2EditField_2 = uieditfield(app.DebugModeTab, 'numeric');
            app.Q2EditField_2.Editable = 'off';
            app.Q3EditField_2Label = uilabel(app.DebugModeTab);
            app.Q3EditField_2Label.Text = 'Q3';
            app.Q3EditField_2 = uieditfield(app.DebugModeTab, 'numeric');
            app.Q3EditField_2.Editable = 'off';
            app.Q4EditField_2Label = uilabel(app.DebugModeTab);
            app.Q4EditField_2Label.Text = 'Q4';
            app.Q4EditField_2 = uieditfield(app.DebugModeTab, 'numeric');
            app.Q4EditField_2.Editable = 'off';
            app.Q5EditField_2Label = uilabel(app.DebugModeTab);
            app.Q5EditField_2Label.Text = 'Q5';
            app.Q5EditField_2 = uieditfield(app.DebugModeTab, 'numeric');
            app.Q5EditField_2.Editable = 'off';
            app.Q6EditField_2Label = uilabel(app.DebugModeTab);
            app.Q6EditField_2Label.Text = 'Q6';
            app.Q6EditField_2 = uieditfield(app.DebugModeTab, 'numeric');
            app.Q6EditField_2.Editable = 'off';
            app.GotoEndEffectorPoseLabel = uilabel(app.DebugModeTab);
            app.GotoEndEffectorPoseLabel.Text = 'Go to End-Effector Pose';
            app.UR31endXEditFieldLabel = uilabel(app.DebugModeTab);
            app.UR31endXEditFieldLabel.Text = 'UR31endX';
            app.UR31endXEditField = uieditfield(app.DebugModeTab, 'numeric');
            app.UR31endXEditField.Value = -0.1;
            app.UR31endYEditFieldLabel = uilabel(app.DebugModeTab);
            app.UR31endYEditFieldLabel.Text = 'UR31endY';
            app.UR31endYEditField = uieditfield(app.DebugModeTab, 'numeric');
            app.UR31endYEditField.Value = 0.45;
            app.UR31endZEditFieldLabel = uilabel(app.DebugModeTab);
            app.UR31endZEditFieldLabel.Text = 'UR31endZ';
            app.UR31endZEditField = uieditfield(app.DebugModeTab, 'numeric');
            app.UR31endZEditField.Value = 0.4;
            app.UR31endRollEditFieldLabel = uilabel(app.DebugModeTab);
            app.UR31endRollEditFieldLabel.Text = 'UR31endRoll';
            app.UR31endRollEditField = uieditfield(app.DebugModeTab, 'numeric');
            app.UR31endRollEditField.Value = -30;
            app.UR31endPitchEditFieldLabel = uilabel(app.DebugModeTab);
            app.UR31endPitchEditFieldLabel.Text = 'UR31endPitch';
            app.UR31endPitchEditField = uieditfield(app.DebugModeTab, 'numeric');
            app.UR31endPitchEditField.Value = 15;
            app.UR31endYawEditFieldLabel = uilabel(app.DebugModeTab);
            app.UR31endYawEditFieldLabel.Text = 'UR31endYaw';
            app.UR31endYawEditField = uieditfield(app.DebugModeTab, 'numeric');
            app.UR31endYawEditField.Value = 30;
            app.UR32endXEditFieldLabel = uilabel(app.DebugModeTab);
            app.UR32endXEditFieldLabel.Text = 'UR32endX';
            app.UR32endXEditField = uieditfield(app.DebugModeTab, 'numeric');
            app.UR32endXEditField.Value = 0.1;
            app.UR32endYEditFieldLabel = uilabel(app.DebugModeTab);
            app.UR32endYEditFieldLabel.Text = 'UR32endY';
            app.UR32endYEditField = uieditfield(app.DebugModeTab, 'numeric');
            app.UR32endYEditField.Value = 0.45;
            app.UR32endZEditFieldLabel = uilabel(app.DebugModeTab);
            app.UR32endZEditFieldLabel.Text = 'UR32endZ';
            app.UR32endZEditField = uieditfield(app.DebugModeTab, 'numeric');
            app.UR32endZEditField.Value = 0.6;
            app.UR32endRollEditFieldLabel = uilabel(app.DebugModeTab);
            app.UR32endRollEditFieldLabel.Text = 'UR32endRoll';
            app.UR32endRollEditField = uieditfield(app.DebugModeTab, 'numeric');
            app.UR32endRollEditField.Value = -30;
            app.UR32endPitchEditFieldLabel = uilabel(app.DebugModeTab);
            app.UR32endPitchEditFieldLabel.Text = 'UR32endPitch';
            app.UR32endPitchEditField = uieditfield(app.DebugModeTab, 'numeric');
            app.UR32endPitchEditField.Value = 15;
            app.UR32endYawEditFieldLabel = uilabel(app.DebugModeTab);
            app.UR32endYawEditFieldLabel.Text = 'UR32endYaw';
            app.UR32endYawEditField = uieditfield(app.DebugModeTab, 'numeric');
            app.UR32endYawEditField.Value = 60;
            app.GoButton = uibutton(app.DebugModeTab, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.WorkspaceCalculationsLabel = uilabel(app.DebugModeTab);
            app.WorkspaceCalculationsLabel.Text = 'Workspace Calculations';
            app.UR31RadiusEditFieldLabel = uilabel(app.DebugModeTab);
            app.UR31RadiusEditFieldLabel.Text = 'UR31 Radius';
            app.UR31RadiusEditField = uieditfield(app.DebugModeTab, 'numeric');
            app.UR31RadiusEditField.Editable = 'off';
            app.UR31RadiusEditField.Value = 0.52;
            app.UR31VolumeConvexLabel = uilabel(app.DebugModeTab);
            app.UR31VolumeConvexLabel.Text = 'UR31 Volume (Convex)';
            app.UR31VolumeConvexEditField = uieditfield(app.DebugModeTab, 'numeric');
            app.UR31VolumeConvexEditField.Editable = 'off';
            app.UR31VolumeConvexEditField.Value = 0.786;
            app.UR32RadiusEditFieldLabel = uilabel(app.DebugModeTab);
            app.UR32RadiusEditFieldLabel.Text = 'UR32 Radius';
            app.UR32RadiusEditField = uieditfield(app.DebugModeTab, 'numeric');
            app.UR32RadiusEditField.Editable = 'off';
            app.UR32RadiusEditField.Value = 0.52;
            app.UR32VolumeConvexEditFieldLabel = uilabel(app.DebugModeTab);
            app.UR32VolumeConvexEditFieldLabel.Text = 'UR32 Volume (Convex)';
            app.UR32VolumeConvexEditField = uieditfield(app.DebugModeTab, 'numeric');
            app.UR32VolumeConvexEditField.Editable = 'off';
            app.UR32VolumeConvexEditField.Value = 0.786;
            app.UR31PCButton = uibutton(app.DebugModeTab, 'push');
            app.UR31PCButton.ButtonPushedFcn = createCallbackFcn(app, @UR31PCButtonPushed, true);
            app.UR31PCButton.Text = 'UR31 PC';
            app.UR32PCButton = uibutton(app.DebugModeTab, 'push');
            app.UR32PCButton.ButtonPushedFcn = createCallbackFcn(app, @UR32PCButtonPushed, true);
            app.UR32PCButton.Text = 'UR32 PC';
            app.GeneratePointCloudandRecalculateLabel = uilabel(app.DebugModeTab);
            app.GeneratePointCloudandRecalculateLabel.Text = 'Generate PointCloud and Re-calculate';
            app.UR31PCButton_2 = uibutton(app.DebugModeTab, 'push');
            app.UR31PCButton_2.ButtonPushedFcn = createCallbackFcn(app, @UR31PCButton_2Pushed, true);
            app.UR31PCButton_2.Enable = 'off';
            app.UR31PCButton_2.Text = 'UR31 PC';
            app.UR32PCButton_2 = uibutton(app.DebugModeTab, 'push');
            app.UR32PCButton_2.ButtonPushedFcn = createCallbackFcn(app, @UR32PCButton_2Pushed, true);
            app.UR32PCButton_2.Enable = 'off';
            app.UR32PCButton_2.Text = 'UR32 PC';
            app.PlotPointCloudLabel = uilabel(app.DebugModeTab);
            app.PlotPointCloudLabel.Text = 'Plot PointCloud';
            app.StepAngleEditFieldLabel = uilabel(app.DebugModeTab);
            app.StepAngleEditFieldLabel.Text = 'StepAngle';
            app.StepAngleEditField = uieditfield(app.DebugModeTab, 'numeric');
            app.StepAngleEditField.Value = 60;
            app.UR31JointAnglesdegCheckBox = uicheckbox(app.DebugModeTab);
            app.UR31JointAnglesdegCheckBox.ValueChangedFcn = createCallbackFcn(app, @UR31JointAnglesdegCheckBoxValueChanged, true);
            app.UR31JointAnglesdegCheckBox.Text = 'UR31 Joint Angles (deg)';
            app.UR32JointAnglesdegCheckBox = uicheckbox(app.DebugModeTab);
            app.UR32JointAnglesdegCheckBox.ValueChangedFcn = createCallbackFcn(app, @UR32JointAnglesdegCheckBoxValueChanged, true);
            app.UR32JointAnglesdegCheckBox.Text = 'UR32 Joint Angles (deg)';
            app.HomeButton = uibutton(app.DebugModeTab, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.DebugTextAreaLabel = uilabel(app.RoboticsAssignment1UIFigure);
            app.DebugTextAreaLabel.Text = 'Debug';
            app.DebugTextArea = uitextarea(app.RoboticsAssignment1UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Assignment1App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RoboticsAssignment1UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RoboticsAssignment1UIFigure)
        end
    end
end